classdef GUI_B_boundary < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TimeEditField               matlab.ui.control.NumericEditField
        TimeEditFieldLabel          matlab.ui.control.Label
        StatusEditField             matlab.ui.control.EditField
        StatusEditFieldLabel        matlab.ui.control.Label
        StopButton                  matlab.ui.control.Button
        ResetFieldButton            matlab.ui.control.Button
        ApplyFIeldEverywhereButton  matlab.ui.control.Button
        BzSlider                    matlab.ui.control.Slider
        BzSliderLabel               matlab.ui.control.Label
        SCTypeButtonGroup           matlab.ui.container.ButtonGroup
        Type1Button                 matlab.ui.control.RadioButton
        Type2Button                 matlab.ui.control.RadioButton
        NzSpinner                   matlab.ui.control.Spinner
        NzSpinnerLabel              matlab.ui.control.Label
        NySpinner                   matlab.ui.control.Spinner
        NySpinnerLabel              matlab.ui.control.Label
        NxSpinner                   matlab.ui.control.Spinner
        NxSpinnerLabel              matlab.ui.control.Label
        StartButton                 matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable = 'off';
            cla(app.UIAxes)
            cla(app.UIAxes2)
            app.stop = 0;
            app.Nx = app.NxSpinner.Value;
            app.Ny = app.NySpinner.Value;
            app.Nz = app.NzSpinner.Value;
            app.Bz = 0;
            if app.SCTypeButtonGroup.Buttons(1).Value == 1
                app.kappaIO = 5;
            else
                app.kappaIO = 0.5;
            end
            
            app.StatusEditField.Value = "Simulation Started";
            trapezoidal_test_GUI(app, event);


        end

        % Button pushed function: ApplyFIeldEverywhereButton
        function ApplyFIeldEverywhereButtonPushed(app, event)
            app.Bz = app.BzSlider.Value;
            app.BzButtonPushed = 1;
        end

        % Button pushed function: ResetFieldButton
        function ResetFieldButtonPushed(app, event)
            app.BzSlider.Value = 0;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stop = 1;
            app.StatusEditField.Value = "Simulation Stopped";
            app.StartButton.Enable = 'on';
        end

        % Value changed function: NxSpinner
        function NxSpinnerValueChanged(app, event)
            value = app.NxSpinner.Value;
            app.NySpinner.Value = value;
        end

        % Button down function: UIAxes, UIFigure
        function UIFigureButtonDown(app, event)
            if ~isempty(app.UIAxes.CurrentPoint)
                app.cord = get(app.UIAxes,'CurrentPoint');
            end
            app.click = 1;  
        end

        % Value changed function: NySpinner
        function NySpinnerValueChanged(app, event)
            value = app.NySpinner.Value;
            app.NxSpinner.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '|\psi|^2')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'B_z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.NxSpinnerLabel = uilabel(app.UIFigure);
            app.NxSpinnerLabel.Text = 'Nx';
            app.NxSpinner = uispinner(app.UIFigure);
            app.NxSpinner.Limits = [3 60];
            app.NxSpinner.ValueChangedFcn = createCallbackFcn(app, @NxSpinnerValueChanged, true);
            app.NxSpinner.Value = 20;
            app.NySpinnerLabel = uilabel(app.UIFigure);
            app.NySpinnerLabel.Text = 'Ny';
            app.NySpinner = uispinner(app.UIFigure);
            app.NySpinner.Limits = [3 60];
            app.NySpinner.ValueChangedFcn = createCallbackFcn(app, @NySpinnerValueChanged, true);
            app.NySpinner.Value = 20;
            app.NzSpinnerLabel = uilabel(app.UIFigure);
            app.NzSpinnerLabel.Text = 'Nz';
            app.NzSpinner = uispinner(app.UIFigure);
            app.NzSpinner.Limits = [3 60];
            app.NzSpinner.Value = 3;
            app.SCTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.SCTypeButtonGroup.Title = 'SC Type';
            app.Type2Button = uiradiobutton(app.SCTypeButtonGroup);
            app.Type2Button.Text = 'Type 2';
            app.Type2Button.Value = true;
            app.Type1Button = uiradiobutton(app.SCTypeButtonGroup);
            app.Type1Button.Text = 'Type 1';
            app.BzSliderLabel = uilabel(app.UIFigure);
            app.BzSliderLabel.Text = 'Bz';
            app.BzSlider = uislider(app.UIFigure);
            app.BzSlider.Limits = [-5 5];
            app.ApplyFIeldEverywhereButton = uibutton(app.UIFigure, 'push');
            app.ApplyFIeldEverywhereButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFIeldEverywhereButtonPushed, true);
            app.ApplyFIeldEverywhereButton.Text = {'Apply FIeld Everywhere'; ''};
            app.ResetFieldButton = uibutton(app.UIFigure, 'push');
            app.ResetFieldButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFieldButtonPushed, true);
            app.ResetFieldButton.Text = 'Reset Field';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.StatusEditField.Editable = 'off';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_B_boundary

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end